classdef StoreFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Learn              matlab.ui.container.Tab
        m_Exchangebtn_2    matlab.ui.control.Button
        Label_5            matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        m_ChooseList_num   matlab.ui.control.ListBox
        m_ChooseList       matlab.ui.control.ListBox
        m_SureBtn_2        matlab.ui.control.Button
        m_OkBtn            matlab.ui.control.Button
        m_ForgetBtn        matlab.ui.control.Button
        m_RememberBtn      matlab.ui.control.Button
        m_Pic              matlab.ui.control.UIAxes
        Store              matlab.ui.container.Tab
        m_Exchangebtn_1    matlab.ui.control.Button
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label_1            matlab.ui.control.Label
        m_Folderlist       matlab.ui.control.ListBox
        m_Quslist          matlab.ui.control.ListBox
        m_Anslist          matlab.ui.control.ListBox
        m_AddFilesbtn      matlab.ui.control.Button
        m_AddFoldersbtn    matlab.ui.control.Button
        m_AddQusbtn        matlab.ui.control.Button
        m_Surebtn          matlab.ui.control.Button
        m_DeleteFolderbtn  matlab.ui.control.Button
        m_DeleteFilebtn    matlab.ui.control.Button
        m_Image            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        RootFolder = {}         %存储题目和照片的根文件夹
        CurrentFolder_names     %所有子文件夹（仅名字）
        CurrentQus_names        %某子文件夹内所有题目（仅名字）
        CurrentAns_names        %某子文件夹内所有答案（仅名字）
        CurrentFolder           %选择的文件夹（仅名字）
        CurrentQus              %选择的题目（仅名字）
        CurrentAns              %选择的答案（仅名字）
        Qus = '题目'
        Ans = '答案'
        m_true = 0;
        m_new_filepath = {};


        RootSituation = {}              %存储学习情况的根文件夹
        ReviewNum = 0                   %复习的数量
        StudyNum = 0                    %本次学习的数量
        LearningGroup = strings(10,1)   %新学习的章节名
        StudyDates0 = zeros(200,1)      %总序号
        StudyDates1 = zeros(200,1)      %app内序号
        StudyDates2 = strings(200,1)    %章名
        StudyDates3 = strings(200,1)    %带后缀的文件名
        StudyDates4 = zeros(200,1)      %当前次数
        StudyDates5 = zeros(200,1)      %总次数
        StudyOrder = zeros(1000)        %学习顺序数组 (app内序号)
        NowWhich = 0                    %位置
        TotalNum = 0                    %学习总数
        FinishNum = 1                   %完成的数量-1
    end

    methods (Access = private)

        function m_ChooseListFolderChange(app)
            folders = dir(app.RootFolder);
            folders(strcmp({folders(:).name}, '.') | strcmp({folders(:).name}, '..')) = [];
            app.m_ChooseList.Items = {folders.name};

            m_number = string(zeros(1, length({folders.name})));
            for i=1:length({folders.name})
                folders_1 = dir(fullfile(app.RootFolder, folders(i).name, app.Qus));
                folders_1(strcmp({folders_1(:).name}, '.') | strcmp({folders_1(:).name}, '..')) = [];
                m_number(i) = [num2str(length({folders_1.name})) '个'];
            end
            app.m_ChooseList_num.Items = m_number;
        end

        function m_RootName(app)
            appPath = mfilename('fullpath');
            [appDir, ~, ~] = fileparts(appPath);
            app.RootSituation = fullfile(appDir, 'Situation');
            app.RootFolder = fullfile(appDir, 'Fileses');
            if ~exist(app.RootFolder, 'dir')
                mkdir(app.RootFolder);
            end
            if ~exist(app.RootSituation, 'dir')
                mkdir(app.RootSituation);
            end
        end

        function m_changeFolderList(app)%%%读取所有文件夹
            folders = dir(app.RootFolder);
            folders(strcmp({folders(:).name}, '.') | strcmp({folders(:).name}, '..')) = [];
            app.CurrentFolder_names = {folders.name};
            %显示子文件夹
            app.m_Folderlist.Items = app.CurrentFolder_names;
        end

        function m_changeQusList(app)%%%读取所有题目
            Ques = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, '*.png'));
            app.CurrentQus_names = {Ques.name};
            %显示文件
            app.m_Quslist.Items = erase(app.CurrentQus_names, ".png");
        end

        function m_changeAnsList(app)%%%读取所有答案
            Anses = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, '*.png'));
            app.CurrentAns_names = {Anses.name};
            %显示文件
            app.m_Anslist.Items = erase(app.CurrentAns_names, ".png");
        end

        function m_SureFolders(app)%%%确保"题目"和"答案"文件夹存在
            app.m_changeFolderList();
            for i=1:length(app.CurrentFolder_names)
                m_newFolders_1 = fullfile(app.RootFolder, app.CurrentFolder_names(i), app.Qus);
                m_newFolders_2 = fullfile(app.RootFolder, app.CurrentFolder_names(i), app.Ans);
                if ~exist(m_newFolders_1{1}, 'dir')
                    mkdir(m_newFolders_1{1});
                end
                if ~exist(m_newFolders_2{1}, 'dir')
                    mkdir(m_newFolders_2{1});
                end
            end
        end

        function m_newAnswer(app, picture, path)%%%新建题目图片
            % 在 UIAxes上添加一个可调整大小和位置的矩形，用于选择要裁剪的区域
            app.m_Surebtn.Visible = "on";
            rec = drawrectangle(app.m_Image);
            rec.Position = [0, 0, size(picture, 2), size(picture, 1)];%默认初始为图片大小
            app.m_Surebtn.Enable = "on";
            uiwait(app.UIFigure);
            % 等待用户选择区域并按下Enter键 或 添加文件作为新题目
            if app.m_true==1
                app.m_true = 0;
                return;
            end
            %若未添加，则继续以下步骤
            wait(rec);
            % 使用 imcrop 函数将所选区域裁剪出来，并将其保存到新的文件中
            new_Qus = imcrop(picture, rec.Position);
            imwrite(new_Qus, path);
            delete(rec);
            %隐藏Surebtn按钮
            m_conceal(app, app.m_Surebtn);
            m_prohibit(app, app.m_Surebtn);
            %隐藏AddQus按钮
            m_conceal(app, app.m_AddQusbtn);
            m_prohibit(app, app.m_AddQusbtn);
        end

        function m_prohibit(~, botton) %禁用按钮
            switch botton.Enable
                case 'on'
                    botton.Enable = 'off';
            end
        end

        function m_allow(~, botton) %恢复按钮
            switch botton.Enable
                case 'off'
                    botton.Enable = 'on';
            end
        end

        function m_conceal(~, botton) %消失按钮
            switch botton.Visible
                case 'on'
                    botton.Visible = 'off';
            end
        end

        function m_reveal(~, botton) %显示按钮
            switch botton.Visible
                case 'off'
                    botton.Visible = 'on';
            end
        end

        function m_showrst(app, msg, position)
            %展示结果
            m_label = uilabel(app.UIFigure, 'Text', msg, 'Position', position);
            m_label.FontSize = 20;
            %2秒后消失
            t = timer;
            t.StartDelay = 2;
            t.TimerFcn = @(~,~)delete(m_label);
            start(t);
        end

        function m_check(app)
            %获取当前文件夹内所有图片数量（序号）
            files_1 = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, '*.png')); %绝对路径
            files_2 = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, '*.png')); %绝对路径
            lastcount_1 = length(files_1);
            lastcount_2 = length(files_2);
            if lastcount_1 < lastcount_2
                for i=1:lastcount_2 - lastcount_1
                    if lastcount_1+i < 10
                        m_number = strcat('0', int2str(lastcount_1 + i));
                    elseif lastcount_1+i >= 10
                        m_number = int2str(lastcount_2 + i);
                    end
                    delete([app.RootFolder '\' app.CurrentFolder '\' app.Ans '\' m_number '.png']);
                end
                %刷新列表
                app.m_changeQusList();
                app.m_changeAnsList();
            elseif lastcount_2 < lastcount_1
                for i=1:lastcount_1 - lastcount_2
                    if lastcount_2+i < 10
                        m_number = strcat('0', int2str(lastcount_2 + i));
                    elseif lastcount_2+i >= 10
                        m_number = int2str(lastcount_2 + i);
                    end
                    delete([app.RootFolder '\' app.CurrentFolder '\' app.Qus '\' m_number '.png']);
                end
                %刷新列表
                app.m_changeQusList();
                app.m_changeAnsList();
            end
        end

        function m_newFolder(app)
            m_length = length(app.m_Folderlist.Items);
            app.m_Folderlist.Items(m_length+1) = cellstr('新建文件夹');
            %提示输入新名称
            prompt = {'输入新文件夹名称：'};
            dlgtitle = '命名';
            dims = [5 50];
            definput = {'新建文件夹'};
            newName = inputdlg(prompt,dlgtitle,dims,definput);
            %更新名称
            app.m_Folderlist.Items(m_length+1) = newName;
            %更新选中的目标
            app.m_Folderlist.Value = newName;
            app.m_FolderlistValueChanged(app);
            m_newFolders_1 = fullfile(app.RootFolder, newName, app.Qus);
            m_newFolders_2 = fullfile(app.RootFolder, newName, app.Ans);
            mkdir(m_newFolders_1{1});
            mkdir(m_newFolders_2{1});
            %取消显示图片
            imshow([], 'Parent', app.m_Image);
        end


        function m_SureTxt(app)
            if ~exist(fullfile(app.RootSituation, 'LastStudy.txt'), 'file')
                fid = fopen(fullfile(app.RootSituation, 'LastStudy.txt'), 'w');
                fclose(fid);
            end
            if ~exist(fullfile(app.RootSituation, 'TotalStudy.txt'), 'file')
                fid = fopen(fullfile(app.RootSituation, 'TotalStudy.txt'), 'w');
                fclose(fid);
            end
            if ~exist(fullfile(app.RootSituation, 'LastStudy_test.txt'), 'file')
                fid = fopen(fullfile(app.RootSituation, 'LastStudy_test.txt'), 'w');
                fclose(fid);
            end
            if ~exist(fullfile(app.RootSituation, 'TotalStudy_test.txt'), 'file')
                fid = fopen(fullfile(app.RootSituation, 'TotalStudy_test.txt'), 'w');
                fclose(fid);
            end
        end

        function m_insert(app, i, x)
            app.StudyOrder(length(app.StudyOrder)+1:-1:i+1) = app.StudyOrder(length(app.StudyOrder):-1:i);
            app.StudyOrder(i) = x;
        end

        function m_ReadDates(app)
            %结果：app.ReviewNum = count_1 + count_2 -重复的  app.TotalNum未涉及 
            count_1 = 0;
            count_2 = 0;

            %%%%%上次背的
            fileInfo_1 = dir(fullfile(app.RootSituation, 'LastStudy.txt'));
            isEmpty_1 = fileInfo_1.bytes == 0;
            fid_1 = fopen(fullfile(app.RootSituation, 'LastStudy.txt'), 'r');
            if ~isEmpty_1  % 检查文件是否为空
                data_1 = textscan(fid_1, '%d %s %s %f');
                count_1 = size(data_1{1}, 1);
                for i = 1 : count_1
                    app.StudyDates0(i) = data_1{1}(i);
                    app.StudyDates2(i) = data_1{2}(i);
                    app.StudyDates3(i) = data_1{3}(i);
                    app.StudyDates5(i) = data_1{4}(i);
                end
                fclose(fid_1);

                %根据上次学习情况，自动选择要复习的单词
                %排序
                for i = 1:count_1
                    for j = 1:count_1-i
                        if app.StudyDates5(j)<app.StudyDates5(j+1)
                            a1 = app.StudyDates5(j+1);
                            b1 = app.StudyDates2(j+1);
                            c1 = app.StudyDates3(j+1);
                            d1 = app.StudyDates0(j+1);
                            app.StudyDates5(j+1) = app.StudyDates5(j);
                            app.StudyDates2(j+1) = app.StudyDates2(j);
                            app.StudyDates3(j+1) = app.StudyDates3(j);
                            app.StudyDates0(j+1) = app.StudyDates0(j);
                            app.StudyDates5(j) = a1;
                            app.StudyDates2(j) = b1;
                            app.StudyDates3(j) = c1;
                            app.StudyDates0(j) = d1;
                        end
                    end
                end

                %只背<=这次学习数 的最生的单词
                if count_1 > app.StudyNum
                    for i = app.StudyNum+1:count_1
                        app.StudyDates2(i) = {};
                        app.StudyDates3(i) = {};
                        app.StudyDates5(i) = 0;
                        app.StudyDates0(i) = 0;
                    end
                    count_1 = app.StudyNum;
                end
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            fid_4 = fopen(fullfile(app.RootSituation, 'LastStudy_test.txt'), 'w');  % 覆盖原文件
            for i = 1 : count_1% 以空格连接
                fprintf(fid_4, '%d ', app.StudyDates0(i));
                fprintf(fid_4, '%s ', app.StudyDates2(i));
                fprintf(fid_4, '%s ', app.StudyDates3(i));
                fprintf(fid_4, '%f\n', app.StudyDates5(i));
            end
            fclose(fid_4);  % 关闭文件


            %%%%%全部的
            fileInfo_2 = dir(fullfile(app.RootSituation, 'TotalStudy.txt'));
            isEmpty_2 = fileInfo_2.bytes == 0;
            fid_2 = fopen(fullfile(app.RootSituation, 'TotalStudy.txt'), 'r');
            if ~isEmpty_2  % 检查文件是否为空
                data_2 = textscan(fid_2, '%d %s %s %f');
                count_2 = size(data_2{1}, 1);
                group0 = zeros(count_2,1);
                group2 = strings(count_2,1);
                group3 = strings(count_2,1);
                group5 = zeros(count_2,1);
                for i = 1 : count_2
                    group0(i) = data_2{1}(i);
                    group2(i) = data_2{2}(i);
                    group3(i) = data_2{3}(i);
                    group5(i) = data_2{4}(i);
                end
                fclose(fid_2);

                %排序
                for i = 1:count_2
                    for j = 1:count_2-i
                        if group5(j)<group5(j+1)
                            a1 = group5(j+1);
                            b1 = group2(j+1);
                            c1 = group3(j+1);
                            d1 = group0(j+1);
                            group5(j+1) = group5(j);
                            group2(j+1) = group2(j);
                            group3(j+1) = group3(j);
                            group0(j+1) = group0(j);
                            group5(j) = a1;
                            group2(j) = b1;
                            group3(j) = c1;
                            group0(j) = d1;
                        end
                    end
                end

                %只背<=这次学习数 的最生的单词
                if count_2 > app.StudyNum
                    for i = app.StudyNum+1:count_2
                        group2(i) = {};
                        group3(i) = {};
                        group5(i) = 0;
                        group0(i) = 0;
                    end
                    count_2 = app.StudyNum;
                end
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            fid_5 = fopen(fullfile(app.RootSituation, 'TotalStudy_test.txt'), 'w');  % 覆盖原文件
            for i = 1 : count_1% 以空格连接
                fprintf(fid_5, '%d ', group0(i));
                fprintf(fid_5, '%s ', group2(i));
                fprintf(fid_5, '%s ', group3(i));
                fprintf(fid_5, '%f\n', group5(i));
            end
            fclose(fid_5);  % 关闭文件

            %合并
            for i = count_1+1 : count_1 + count_2
                app.StudyDates2(i) = group2(i-count_1);
                app.StudyDates3(i) = group3(i-count_1);
                app.StudyDates5(i) = group5(i-count_1);
                app.StudyDates0(i) = group0(i-count_1);
            end

            for i = 1 : count_1 + count_2
                app.StudyDates5(i) = 0;
            end

            app.ReviewNum = count_1 + count_2;
            if count_1~=0 && count_2~=0
                %去除总次数相等的重复部分
                a = app.ReviewNum;
                for i = app.ReviewNum : -1 : 2
                    if (app.StudyDates2(i) == app.StudyDates2(i-1)) && (app.StudyDates3(i) == app.StudyDates3(i-1)) % 重复
                        for j = i-1 : a-1
                            app.StudyDates2(j) = app.StudyDates2(j+1);
                            app.StudyDates3(j) = app.StudyDates3(j+1);
                            app.StudyDates5(j) = app.StudyDates5(j+1);
                            app.StudyDates0(j) = app.StudyDates0(j+1);
                        end
                        a = a-1;
                    end
                end
                app.ReviewNum = a;
            end

            %整理背诵顺序
            for i = 1:app.ReviewNum
                app.StudyOrder(i) = app.StudyDates0(i);
            end
        end

        function m_nextQus(app)
            %如果背完了
            if app.FinishNum>app.TotalNum
                app.FinishNum = app.FinishNum - 1;
                app.Label_4.Text = num2str(app.FinishNum);
                %弹出弹窗，可关闭
                confirmed = uiconfirm(app.UIFigure, '已背完，恭喜您！', 'CONGRATULATION', 'Options', {'退出', '取消'}, 'DefaultOption', '退出');
                if strcmp(confirmed, '退出')
                    m_close(app);
                    return;
                end
            end

            app.NowWhich = app.NowWhich + 1;

            myImage = imread(fullfile(app.RootFolder, app.StudyDates2(app.StudyOrder(app.NowWhich)), app.Qus, app.StudyDates3(app.StudyOrder(app.NowWhich))));
            imshow(myImage, 'Parent', app.m_Pic);
        end

        function m_nextAns(app)
            myImage = imread(fullfile(app.RootFolder, app.StudyDates2(app.StudyOrder(app.NowWhich)), app.Ans, app.StudyDates3(app.StudyOrder(app.NowWhich))));
            imshow(myImage, 'Parent', app.m_Pic);
        end

        function m_close(app)
            %%保存数据文件
            %本次学习情况
            fid_1 = fopen(fullfile(app.RootSituation, 'LastStudy.txt'), 'w');  % 覆盖原文件

            for i = 1 : app.StudyNum % 以空格连接
                fprintf(fid_1, '%d ', app.StudyDates0(i+app.ReviewNum));
                fprintf(fid_1, '%s ', app.StudyDates2(i+app.ReviewNum));
                fprintf(fid_1, '%s ', app.StudyDates3(i+app.ReviewNum));
                fprintf(fid_1, '%f\n', app.StudyDates5(i+app.ReviewNum));
            end
            fclose(fid_1);  % 关闭文件

            %修改总文件
            fileInfo_2 = dir(fullfile(app.RootSituation, 'TotalStudy.txt'));
            isEmpty_2 = fileInfo_2.bytes == 0;
            fid_2 = fopen(fullfile(app.RootSituation, 'TotalStudy.txt'), 'r');
            if ~isEmpty_2  % 若TotalStudy非空
                data_2 = textscan(fid_2, '%d %s %s %f');
                app.ReviewNum = size(data_2{1}, 1);
                app.TotalNum = app.ReviewNum;
                count = size(data_2{1}, 1) + app.StudyNum; % 预留多余的位置给新学习的内容（假设全是新题目)
                group0 = zeros(count,1);
                group2 = strings(count,1);
                group3 = strings(count,1);
                group5 = zeros(count,1);
                count = count - app.StudyNum;
                for i = 1 : count
                    group0(i) = data_2{1}(i);
                    group2(i) = data_2{2}(i);
                    group3(i) = data_2{3}(i);
                    group5(i) = data_2{4}(i);
                end

                a = count;
                for i = 1 : app.TotalNum
                    for j = 1 : a
                        if app.StudyDates0(i) == group0(j) %若存在
                            group5(j) = app.StudyDates5(i)/2 + group5(j)/2;
                            break;
                        end
                        if j == a %若为新题目
                            count = count + 1;
                            group0(count) = count;
                            group2(count) = app.StudyDates2(i);
                            group3(count) = app.StudyDates3(i);
                            group5(count) = app.StudyDates5(i);
                        end
                    end
                end
                fclose(fid_2);  % 关闭文件
            else % 若TotalStudy为空
                group0 = zeros(app.TotalNum,1);
                group2 = strings(app.TotalNum,1);
                group3 = strings(app.TotalNum,1);
                group5 = zeros(app.TotalNum,1);
                for i = 1 : app.TotalNum
                    group0(i) = app.StudyDates0(i);
                    group2(i) = app.StudyDates2(i);
                    group3(i) = app.StudyDates3(i);
                    group5(i) = app.StudyDates5(i);
                end
            end

            fid_3 = fopen(fullfile(app.RootSituation, 'TotalStudy.txt'), 'w');  % 覆盖原文件
            for i = 1 : app.TotalNum% 以空格连接
                fprintf(fid_3, '%d ', group0(i));
                fprintf(fid_3, '%s ', group2(i));
                fprintf(fid_3, '%s ', group3(i));
                fprintf(fid_3, '%f\n', group5(i));
            end
            fclose(fid_3);  % 关闭文件

            %关闭软件
            close(app.UIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %获取屏幕大小
            screen_size = get(groot, 'ScreenSize');
            %设置窗口位置和大小
            app.UIFigure.Position = [0, 0, screen_size(3), screen_size(4)];
            %全屏
            set(app.UIFigure, 'WindowState', 'maximized');
            %清空图片显示
            imshow([], 'Parent', app.m_Image);
            imshow([], 'Parent', app.m_Pic);
            %不显示uiaxes工具栏
            app.m_Image.Toolbar.Visible = 'off';
            app.m_Pic.Toolbar.Visible = 'off';
            
            %获取根目录
            app.m_RootName();
            %确保txt文件存在
            app.m_SureTxt();
            %确保"题目"和"答案"文件夹存在 %显示默认列表内文件
            app.m_SureFolders();
            %选择列表初始化
            app.m_ChooseListFolderChange();
        end

        % Button pushed function: m_AddFilesbtn
        function m_AddFilesbtnPushed(app, event)
            %消失addFodlers按钮
            m_conceal(app, app.m_AddFoldersbtn);

            %隐藏DeleteFolder按钮
            m_conceal(app, app.m_DeleteFolderbtn);
            m_prohibit(app, app.m_DeleteFolderbtn);

            %获取当前文件夹内所有图片数量（序号）
            files = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, '*.png')); %绝对路径
            lastcount = length(files);

            %用户选择图片
            [filename, filepath] = uigetfile({'*.png'}, '选择要添加的图片');
            if isequal(filename, 0) || isequal(filepath, 0)
                %用户取消选择
                %恢复AddFolders按钮
                m_reveal(app, app.m_AddFoldersbtn);
                m_allow(app, app.m_AddFoldersbtn);
                %恢复DeleteFolders按钮
                m_reveal(app, app.m_DeleteFolderbtn);
                m_allow(app, app.m_DeleteFolderbtn);
                return;
            end

            %确定新名字+新路径
            [~, ~, ext] = fileparts(filename);
            switch lastcount+1
                case {1,2,3,4,5,6,7,8,9}
                    new_filename = ['0' int2str(lastcount+1) ext]; %纯文件名+后缀
                otherwise
                    new_filename = [int2str(lastcount+1) ext]; %纯文件名+后缀
            end
            new_filepath = fullfile(app.RootFolder, app.CurrentFolder, app.Ans, new_filename); %完整路径

            %复制
            try
                copyfile(fullfile(filepath, filename), new_filepath);
                msg = '答案添加成功！';
            catch ME
                msg = ['答案添加失败： ' ME.message];
            end

            %展示结果
            m_showrst(app, msg, [145, 190, 500, 40]);

            %刷新答案列表
            app.m_changeAnsList();

            %改变选中文件
            app.m_Anslist.Value = strrep(new_filename, '.png', '');
            app.CurrentAns = [app.m_Anslist.Value '.png'];
            %显示新图片
            myImage = imread(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, app.CurrentAns));
            imshow(myImage, 'Parent', app.m_Image);

            %取消题目列表的选中
            app.CurrentQus = {};
            app.m_Quslist.Value = {};

            %禁用add按钮
            m_prohibit(app, app.m_AddFilesbtn);

            %%%创建题目
            m_label = uilabel(app.UIFigure, 'Text', '请单击图片截取题目！', 'Position', [665, 750, 400, 100]);
            m_label.FontSize = 36;
            m_label.FontWeight = 'bold';
            new_filepath = strrep(new_filepath, app.Ans, app.Qus);
            app.m_new_filepath = new_filepath;

            %显示&恢复addQus按钮
            m_reveal(app, app.m_AddQusbtn);
            m_allow(app, app.m_AddQusbtn);
            try
                m_newAnswer(app, myImage, new_filepath);
                msg = '题目添加成功！';
            catch ME
                msg = ['题目添加失败： ' ME.message];
            end
            delete(m_label);

            %展示结果
            m_showrst(app, msg, [500, 190, 500, 40]);

            %恢复addFiles, addFolders按钮
            m_allow(app, app.m_AddFilesbtn);
            m_reveal(app, app.m_AddFoldersbtn);

            %刷新题目列表
            app.m_changeQusList();
            %%以下是m_QuslistValueChanged(app, event)函数
            %改变选中题目
            app.m_Quslist.Value = app.m_Anslist.Value;
            app.CurrentAns = {};
            app.CurrentQus = [app.m_Quslist.Value, '.png'];
            app.m_Anslist.Value = {};
            %恢复delete按钮
            m_allow(app, app.m_DeleteFilebtn);
            %展示选中题目

            myImage = imread(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, app.CurrentQus));
            imshow(myImage, 'Parent', app.m_Image);
            %%end

            %隐藏Deletebtn按钮
            m_conceal(app, app.m_DeleteFilebtn);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%app.UIFigure.WindowStyle = "modal"; %保证窗口最前
        end

        % Button pushed function: m_DeleteFilebtn
        function m_DeleteFilebtnPushed(app, event)
            if ~isempty(app.m_Quslist.Value) && isempty(app.m_Anslist.Value) %如果选中的是题目，则题目答案一起删除
                %确认是否删除
                confirmed = uiconfirm(app.UIFigure, '是否确认删除题目+答案？', '确认删除', 'Options', {'确认', '取消'}, 'DefaultOption', '取消');
                if strcmp(confirmed, '确认') %执行以下操作
                    %删除
                    try
                        delete(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, app.CurrentQus)); %删题目
                        delete(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, app.CurrentQus)); %删答案
                        msg = '删除成功！';
                    catch ME
                        msg = ['删除失败： ' ME.message];
                    end
                    m_showrst(app, msg, [860, 190, 500, 40]);

                    %重命名剩余文件
                    %获取当前文件夹内所有图片数量（序号）
                    files = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, '*.png')); %绝对路径
                    %判断是否需要修改
                    for i=1:length(files)
                        oldpath = fullfile(app.RootFolder, app.CurrentFolder, app.Qus, files(i).name);
                        switch i
                            case {1,2,3,4,5,6,7,8,9}
                                newpath = [app.RootFolder '\' app.CurrentFolder '\' app.Qus '\' sprintf('0%d.png', i)];
                            otherwise
                                newpath = [app.RootFolder '\' app.CurrentFolder '\' app.Qus '\' sprintf('%d.png', i)];
                        end
                        if ~strcmp(oldpath, newpath)
                            movefile(oldpath, newpath);
                            oldpath = strrep(oldpath, app.Qus, app.Ans);
                            newpath = strrep(newpath, app.Qus, app.Ans);
                            movefile(oldpath, newpath);
                        end
                    end
                end
            elseif isempty(app.m_Quslist.Value) && ~isempty(app.m_Anslist.Value) %如果选择的是答案，则只删答案
                %确认是否删除
                confirmed = uiconfirm(app.UIFigure, '是否确认删除该答案（不会删除题目)？', '确认删除', 'Options', {'确认', '取消'}, 'DefaultOption', '取消');
                if strcmp(confirmed, '确认') %执行以下操作
                    %删除
                    new_filepath = fullfile(app.RootFolder, app.CurrentFolder, app.Ans, app.CurrentAns);
                    try
                        delete(new_filepath); %删答案
                        msg = '删除成功！';
                    catch ME
                        msg = ['删除失败： ' ME.message];
                    end
                    m_showrst(app, msg, [860, 190, 500, 40]);

                    %要求重新添加答案
                    %(请重新截取答案）
                    m_label = uilabel(app.UIFigure, 'Text', '请重新添加答案！', 'Position', [665, 700, 400, 100]);
                    m_label.FontSize = 36;
                    m_label.FontWeight = 'bold';

                    new_filepath = strrep(new_filepath, app.Qus, app.Ans);
                    %展示题目
                    app.m_Quslist.Value = app.m_Anslist.Value;
                    app.CurrentQus = app.m_Quslist.Value;
                    myImage = imread([fullfile(app.RootFolder, app.CurrentFolder, app.Qus, app.CurrentQus) '.png']);
                    imshow(myImage, 'Parent', app.m_Image);
                    try
                        m_newAnswer(app, myImage, new_filepath);
                        msg = '答案添加成功！';
                    catch ME
                        msg = ['答案添加失败： ' ME.message];
                    end
                    m_showrst(app, msg, [500, 190, 500, 40]);
                    delete(m_label);
                end
            end


            %刷新列表
            app.m_changeQusList();
            app.m_changeAnsList();

            %取消正在选择的
            app.m_Quslist.Value = {};
            app.m_Anslist.Value = {};

            %禁用delete按钮
            m_prohibit(app, app.m_DeleteFilebtn);

            %清空图片显示
            imshow([], 'Parent', app.m_Image);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: m_Folderlist
        function m_FolderlistValueChanged(app, event)
            %恢复add_Files按钮
            m_allow(app, app.m_AddFilesbtn);

            %禁用delete按钮
            m_prohibit(app, app.m_DeleteFilebtn);

            %改变选中文件夹
            app.CurrentFolder = app.m_Folderlist.Value;

            %刷新文件列表
            app.m_changeQusList();
            app.m_changeAnsList();

            %检测是否题目/答案不对等
            app.m_check();

            %隐藏Deletebtn按钮
            m_conceal(app, app.m_DeleteFilebtn);

            %显示add_Files按钮
            m_reveal(app, app.m_AddFilesbtn);

            %显示DeleteFolder按钮
            m_reveal(app, app.m_DeleteFolderbtn);
            m_allow(app, app.m_DeleteFolderbtn);

            %不显示图片
            imshow([], 'Parent', app.m_Image);
        end

        % Value changed function: m_Quslist
        function m_QuslistValueChanged(app, event)
            %改变选中题目
            app.CurrentQus = [app.m_Quslist.Value, '.png'];
            app.CurrentAns = {};
            app.m_Anslist.Value = {};

            %恢复delete按钮
            m_allow(app, app.m_DeleteFilebtn);

            %展示选中题目
            myImage = imread(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, app.CurrentQus));
            imshow(myImage, 'Parent', app.m_Image);

            %显示删除按钮
            m_reveal(app, app.m_DeleteFilebtn);

            %隐藏DeleteFolder按钮
            m_conceal(app, app.m_DeleteFolderbtn);
            m_prohibit(app, app.m_DeleteFolderbtn);
        end

        % Value changed function: m_Anslist
        function m_AnslistValueChanged(app, event)
            %改变选中答案
            app.CurrentQus = {};
            app.CurrentAns = [app.m_Anslist.Value, '.png'];
            app.m_Quslist.Value = {};

            %恢复delete按钮
            m_allow(app, app.m_DeleteFilebtn);

            %展示选中答案
            myImage = imread(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, app.CurrentAns));
            imshow(myImage, 'Parent', app.m_Image);

            %显示删除按钮
            m_reveal(app, app.m_DeleteFilebtn);

            %隐藏DeleteFolder按钮
            m_conceal(app, app.m_DeleteFolderbtn);
            m_prohibit(app, app.m_DeleteFolderbtn);
        end

        % Button pushed function: m_Surebtn
        function m_SurebtnButtonPushed(app, event)
            % 创建robot对象
            r = java.awt.Robot;
            % 模拟按下Enter键
            r.keyPress(java.awt.event.KeyEvent.VK_ENTER);
            % 模拟释放Enter键
            r.keyRelease(java.awt.event.KeyEvent.VK_ENTER);
            uiresume(app.UIFigure);
        end

        % Button pushed function: m_AddFoldersbtn
        function m_AddFoldersbtnButtonPushed(app, event)
            app.m_newFolder();
        end

        % Button pushed function: m_AddQusbtn
        function m_AddQusbtnButtonPushed(app, event)
            %用户选择图片
            [filename, filepath] = uigetfile({'*.png'}, '选择要添加的图片');
            if isequal(filename, 0) || isequal(filepath, 0)
                %用户取消选择
                return;
            end

            %复制
            try
                copyfile(fullfile(filepath, filename), app.m_new_filepath);
                app.m_true = 1;
            catch
                app.m_true = 0;
            end

            uiresume(app.UIFigure);
        end

        % Value changed function: m_ChooseList_num
        function m_ChooseList_numValueChanged(app, event)
            %不显示选中
            app.m_ChooseList_num.Value = {};
        end

        % Button pushed function: m_SureBtn_2
        function m_SureBtn_2ButtonPushed(app, event)
            app.LearningGroup = string(app.m_ChooseList.Value);
            m_conceal(app, app.m_ChooseList);
            m_prohibit(app, app.m_ChooseList);
            m_conceal(app, app.m_ChooseList_num);
            m_prohibit(app, app.m_ChooseList_num);
            m_conceal(app, app.m_SureBtn_2);
            m_prohibit(app, app.m_SureBtn_2);
            m_conceal(app, app.m_Exchangebtn_2);
            m_prohibit(app, app.m_Exchangebtn_2);

            %准备学习数组
            m_filepath = fullfile(app.RootFolder, app.LearningGroup, app.Qus);
            lastcount = 1;

            fileInfo = dir(fullfile(app.RootSituation, 'TotalStudy.txt'));
            isEmpty = fileInfo.bytes == 0;
            fid = fopen(fullfile(app.RootSituation, 'TotalStudy.txt'), 'r');
            count = 0;
            if ~isEmpty  % 若文件非空，读取数据
                m_data = textscan(fid, '%d %s %s %f');
                count = size(m_data{1}, 1);
                group0 = zeros(count,1);
                group2 = strings(count,1);
                group3 = strings(count,1);
                group5 = zeros(count,1);
                for i = 1 : count
                    group0(i) = m_data{1}(i);
                    group2(i) = m_data{2}(i);
                    group3(i) = m_data{3}(i);
                    group5(i) = m_data{4}(i);
                end
            end

            %确认本次学习的数量Studycount
            for i=1:length(m_filepath)
                files = dir(m_filepath(i));
                files(strcmp({files(:).name}, '.') | strcmp({files(:).name}, '..')) = [];
                app.StudyNum = app.StudyNum + length(files);
            end

            %%%%%%%%%%%%%%%读取Situation数据
            app.m_ReadDates();

            %读取本次学习的内容
            a = count;
            for i=1:length(m_filepath)
                files = dir(m_filepath(i));
                files(strcmp({files(:).name}, '.') | strcmp({files(:).name}, '..')) = [];
                for j=1:length(files)
                    app.StudyDates1(app.ReviewNum + lastcount) = app.ReviewNum + lastcount;  %第一项 app内序号
                    app.StudyDates2(app.ReviewNum + lastcount) = app.LearningGroup(i);       %第二项 章名
                    app.StudyDates3(app.ReviewNum + lastcount) = files(j).name;              %第三项 带后缀的文件名
                    app.StudyDates4(app.ReviewNum + lastcount) = 0;                          %第四项 当前次数
                    app.StudyDates5(app.ReviewNum + lastcount) = 0;                          %第五项 总次数
                    app.StudyOrder(app.ReviewNum + lastcount) = lastcount;                   %出现的顺序
                    if ~isEmpty  % 检查文件是否非空
                        % 检索是否在TotalStudy.txt出现过
                        for m = 1 : a
                            if strcmp(group3(m), files(j).name) && strcmp(group2(m), app.LearningGroup(i))  
                                app.StudyDates0(app.ReviewNum + lastcount) = group0(m);          %总序号
                                break;
                            end
                            % 若未检测到，赋予新的编号
                            if m == a
                                app.StudyDates0(app.ReviewNum + lastcount) = count + 1;
                                count = count + 1;
                            end
                        end
                    else
                        app.StudyDates0(app.ReviewNum + lastcount) = count + 1;
                        count = count + 1;
                    end
                    lastcount = lastcount+1;
                end
            end
            fclose(fid);
            app.TotalNum = app.ReviewNum + app.StudyNum;

            %左上角label
            app.Label_4.Text = '1';
            app.Label_5.Text = ['/' num2str(app.TotalNum)];

            %显示图像
            app.m_nextQus();

            %按钮
            m_reveal(app, app.m_ForgetBtn);
            m_allow(app, app.m_ForgetBtn);
            m_reveal(app, app.m_RememberBtn);
            m_allow(app, app.m_RememberBtn);
        end

        % Button pushed function: m_ForgetBtn
        function m_ForgetBtnButtonPushed(app, event)
            m_conceal(app, app.m_ForgetBtn);
            m_prohibit(app, app.m_ForgetBtn);
            m_conceal(app, app.m_RememberBtn);
            m_prohibit(app, app.m_RememberBtn);
            m_reveal(app, app.m_OkBtn);
            m_allow(app, app.m_OkBtn);

            app.m_nextAns();

            app.StudyDates4(app.StudyOrder(app.NowWhich)) = 1;
            app.StudyDates5(app.StudyOrder(app.NowWhich)) = app.StudyDates5(app.StudyOrder(app.NowWhich)) + 1;
            if app.StudyOrder(app.NowWhich+7) ~= 0
                m_insert(app, app.NowWhich+7, app.StudyOrder(app.NowWhich));
            else
                for i=6:-1:0
                    if app.StudyOrder(app.NowWhich+i) == 0
                        continue;
                    end
                    app.StudyOrder(app.NowWhich+i+1) = app.StudyOrder(app.NowWhich);
                    break;
                end
            end
        end

        % Button pushed function: m_RememberBtn
        function m_RememberBtnButtonPushed(app, event)
            m_conceal(app, app.m_ForgetBtn);
            m_prohibit(app, app.m_ForgetBtn);
            m_conceal(app, app.m_RememberBtn);
            m_prohibit(app, app.m_RememberBtn);
            m_reveal(app, app.m_OkBtn);
            m_allow(app, app.m_OkBtn);

            app.m_nextAns();

            switch app.StudyDates4(app.StudyOrder(app.NowWhich))
                case 0
                    app.StudyDates4(app.StudyOrder(app.NowWhich)) = 4;
                case 1
                    if app.StudyOrder(app.NowWhich+7) ~= 0
                        m_insert(app, app.NowWhich+7, app.StudyOrder(app.NowWhich));
                    else
                        for i=6:-1:0
                            if app.StudyOrder(app.NowWhich+i) == 0
                                continue;
                            end
                            app.StudyOrder(app.NowWhich+i+1) = app.StudyOrder(app.NowWhich);
                            break;
                        end
                    end
                    app.StudyDates4(app.StudyOrder(app.NowWhich)) = app.StudyDates4(app.StudyOrder(app.NowWhich)) + 1;
                case 2
                    if app.StudyOrder(app.NowWhich+14) ~= 0
                        m_insert(app, app.NowWhich+14, app.StudyOrder(app.NowWhich));
                    else
                        for i=13:-1:0
                            if app.StudyOrder(app.NowWhich+i) == 0
                                continue;
                            end
                            app.StudyOrder(app.NowWhich+i+1) = app.StudyOrder(app.NowWhich);
                            break;
                        end
                    end
                    app.StudyDates4(app.StudyOrder(app.NowWhich)) = app.StudyDates4(app.StudyOrder(app.NowWhich)) + 1;
                case 3
                    if app.StudyOrder(app.NowWhich+21) ~= 0
                        m_insert(app, app.NowWhich+21, app.StudyOrder(app.NowWhich));
                    else
                        for i=20:-1:0
                            if app.StudyOrder(app.NowWhich+i) == 0
                                continue;
                            end
                            app.StudyOrder(app.NowWhich+i+1) = app.StudyOrder(app.NowWhich);
                            break;
                        end
                    end
                    app.StudyDates4(app.StudyOrder(app.NowWhich)) = app.StudyDates4(app.StudyOrder(app.NowWhich)) + 1;
            end

        end

        % Button pushed function: m_OkBtn
        function m_OkBtnButtonPushed(app, event)
            if app.StudyDates4(app.StudyOrder(app.NowWhich)) == 4
                app.FinishNum = app.FinishNum + 1;
                app.Label_4.Text = num2str(app.FinishNum);
            end

            m_nextQus(app);%%%%%%%%%在此时NowWhich自增了%%%%%%%%%%%%%

            %恢复Remember按钮
            m_reveal(app, app.m_RememberBtn);
            m_allow(app, app.m_RememberBtn);
            %恢复Forget按钮
            m_reveal(app, app.m_ForgetBtn);
            m_allow(app, app.m_ForgetBtn);
            %隐藏Ok按钮
            m_conceal(app, app.m_OkBtn);
            m_prohibit(app, app.m_OkBtn);
        end

        % Button pushed function: m_DeleteFolderbtn
        function m_DeleteFolderbtnButtonPushed(app, event)
            confirmed = uiconfirm(app.UIFigure, '是否确认删除该文件夹及包含的全部文件？', '确认删除', 'Options', {'确认', '取消'}, 'DefaultOption', '取消');
            if strcmp(confirmed, '确认') %执行以下操作
                %删除
                try
                    rmdir(fullfile(app.RootFolder, app.CurrentFolder), 's');
                    msg = '删除成功！';
                catch ME
                    msg = ['删除失败： ' ME.message];
                end
                m_showrst(app, msg, [860, 190, 500, 40]);
            end

            %刷新文件夹列表
            m_changeFolderList(app);

            %改变选中文件夹
            app.CurrentFolder = app.m_Folderlist.Value;

            %刷新文件列表
            app.m_changeQusList();
            app.m_changeAnsList();
        end

        % Button pushed function: m_Exchangebtn_1
        function m_Exchangebtn_1ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Learn;
        end

        % Button pushed function: m_Exchangebtn_2
        function m_Exchangebtn_2ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Store;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Learn = uitab(app.TabGroup);
            app.Learn.Title = '背诵';
            app.m_Pic = uiaxes(app.Learn);
            app.m_RememberBtn = uibutton(app.Learn, 'push');
            app.m_RememberBtn.ButtonPushedFcn = createCallbackFcn(app, @m_RememberBtnButtonPushed, true);
            app.m_RememberBtn.Enable = 'off';
            app.m_RememberBtn.Visible = 'off';
            app.m_RememberBtn.Text = '记得';
            app.m_ForgetBtn = uibutton(app.Learn, 'push');
            app.m_ForgetBtn.ButtonPushedFcn = createCallbackFcn(app, @m_ForgetBtnButtonPushed, true);
            app.m_ForgetBtn.Enable = 'off';
            app.m_ForgetBtn.Visible = 'off';
            app.m_ForgetBtn.Text = '忘了';
            app.m_OkBtn = uibutton(app.Learn, 'push');
            app.m_OkBtn.ButtonPushedFcn = createCallbackFcn(app, @m_OkBtnButtonPushed, true);
            app.m_OkBtn.Enable = 'off';
            app.m_OkBtn.Visible = 'off';
            app.m_OkBtn.Text = '好的';
            app.m_SureBtn_2 = uibutton(app.Learn, 'push');
            app.m_SureBtn_2.ButtonPushedFcn = createCallbackFcn(app, @m_SureBtn_2ButtonPushed, true);
            app.m_SureBtn_2.Text = '确定';
            app.m_ChooseList = uilistbox(app.Learn);
            app.m_ChooseList.Items = {};
            app.m_ChooseList.Multiselect = 'on';
            app.m_ChooseList.Value = {};
            app.m_ChooseList_num = uilistbox(app.Learn);
            app.m_ChooseList_num.Items = {};
            app.m_ChooseList_num.ValueChangedFcn = createCallbackFcn(app, @m_ChooseList_numValueChanged, true);
            app.m_ChooseList_num.Value = {};
            app.Label_4 = uilabel(app.Learn);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.Learn);
            app.Label_5.Text = '';
            app.m_Exchangebtn_2 = uibutton(app.Learn, 'push');
            app.m_Exchangebtn_2.ButtonPushedFcn = createCallbackFcn(app, @m_Exchangebtn_2ButtonPushed, true);
            app.m_Exchangebtn_2.Text = '切换页面';
            app.Store = uitab(app.TabGroup);
            app.Store.Title = '题库';
            app.m_Image = uiaxes(app.Store);
            app.m_DeleteFilebtn = uibutton(app.Store, 'push');
            app.m_DeleteFilebtn.ButtonPushedFcn = createCallbackFcn(app, @m_DeleteFilebtnPushed, true);
            app.m_DeleteFilebtn.Enable = 'off';
            app.m_DeleteFilebtn.Visible = 'off';
            app.m_DeleteFilebtn.Text = '删除';
            app.m_DeleteFolderbtn = uibutton(app.Store, 'push');
            app.m_DeleteFolderbtn.ButtonPushedFcn = createCallbackFcn(app, @m_DeleteFolderbtnButtonPushed, true);
            app.m_DeleteFolderbtn.Enable = 'off';
            app.m_DeleteFolderbtn.Visible = 'off';
            app.m_DeleteFolderbtn.Text = '删除文件夹';
            app.m_Surebtn = uibutton(app.Store, 'push');
            app.m_Surebtn.ButtonPushedFcn = createCallbackFcn(app, @m_SurebtnButtonPushed, true);
            app.m_Surebtn.Enable = 'off';
            app.m_Surebtn.Visible = 'off';
            app.m_Surebtn.Text = '确认';
            app.m_AddQusbtn = uibutton(app.Store, 'push');
            app.m_AddQusbtn.ButtonPushedFcn = createCallbackFcn(app, @m_AddQusbtnButtonPushed, true);
            app.m_AddQusbtn.Enable = 'off';
            app.m_AddQusbtn.Visible = 'off';
            app.m_AddQusbtn.Text = '从外界添加';
            app.m_AddFoldersbtn = uibutton(app.Store, 'push');
            app.m_AddFoldersbtn.ButtonPushedFcn = createCallbackFcn(app, @m_AddFoldersbtnButtonPushed, true);
            app.m_AddFoldersbtn.Text = '添加文件夹';
            app.m_AddFilesbtn = uibutton(app.Store, 'push');
            app.m_AddFilesbtn.ButtonPushedFcn = createCallbackFcn(app, @m_AddFilesbtnPushed, true);
            app.m_AddFilesbtn.Enable = 'off';
            app.m_AddFilesbtn.Visible = 'off';
            app.m_AddFilesbtn.Text = '添加';
            app.m_Anslist = uilistbox(app.Store);
            app.m_Anslist.Items = {};
            app.m_Anslist.ValueChangedFcn = createCallbackFcn(app, @m_AnslistValueChanged, true);
            app.m_Anslist.Value = {};
            app.m_Quslist = uilistbox(app.Store);
            app.m_Quslist.Items = {};
            app.m_Quslist.ValueChangedFcn = createCallbackFcn(app, @m_QuslistValueChanged, true);
            app.m_Quslist.Value = {};
            app.m_Folderlist = uilistbox(app.Store);
            app.m_Folderlist.Items = {};
            app.m_Folderlist.ValueChangedFcn = createCallbackFcn(app, @m_FolderlistValueChanged, true);
            app.m_Folderlist.Value = {};
            app.Label_1 = uilabel(app.Store);
            app.Label_1.Text = '文件夹';
            app.Label_2 = uilabel(app.Store);
            app.Label_2.Text = '题目';
            app.Label_3 = uilabel(app.Store);
            app.Label_3.Text = '答案';
            app.m_Exchangebtn_1 = uibutton(app.Store, 'push');
            app.m_Exchangebtn_1.ButtonPushedFcn = createCallbackFcn(app, @m_Exchangebtn_1ButtonPushed, true);
            app.m_Exchangebtn_1.Text = '切换页面';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StoreFile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end