classdef ChangeFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        m_Anslist     matlab.ui.control.ListBox
        m_Filelist    matlab.ui.control.ListBox
        m_Folderlist  matlab.ui.control.ListBox
        m_Delete      matlab.ui.control.Button
        m_Add         matlab.ui.control.Button
        m_Image       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RootFolder = 'e:\study\Matlab_test' %根文件夹
        CurrentFolder_names %所有子文件夹（仅名字）
        CurrentFile_names %某子文件夹内所有文件（仅名字）
        CurrentFolder %选择的文件夹（仅名字）
        CurrentFile %选择的文件（仅名字）
    end
    
    methods (Access = private)
        
        function m_changeFolderList(app)%%%读取所有文件夹
            folders = dir(app.RootFolder);
            folders(strcmp({folders(:).name}, '.') | strcmp({folders(:).name}, '..')) = [];
            app.CurrentFolder_names = {folders.name};

            %显示子文件夹
            app.m_Folderlist.Items = app.CurrentFolder_names;
        end

        function m_changeFileList(app)%%%读取所有文件名
            files = dir(fullfile(app.RootFolder, app.CurrentFolder, '*.png'));
            app.CurrentFile_names = {files.name};

            %显示文件
            app.m_Filelist.Items = app.CurrentFile_names;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIFigure, 'WindowState', 'maximized'); %全屏
            app.m_changeFolderList;  %显示默认列表内文件
            imshow([], 'Parent', app.m_Image); %清空图片显示
            app.m_Image.Toolbar.Visible = 'off'; %不显示uiaxes工具栏
        end

        % Button pushed function: m_Add
        function m_AddPushed(app, event)
            %获取当前文件夹内所有图片数量（序号）
            files = dir(fullfile(app.RootFolder, app.CurrentFolder, '*.png')); %绝对路径
            lastcount = length(files);

            %用户选择图片
            [filename, filepath] = uigetfile({'*.png'}, '选择要添加的图片');
            if isequal(filename, 0) || isequal(filepath, 0)
                %用户取消选择
                return;
            end

            %确定新名字+新路径
            [~, ~, ext] = fileparts(filename);
            switch lastcount+1
                case {1,2,3,4,5,6,7,8,9}
                    new_filename = [app.CurrentFolder '_0' int2str(lastcount+1) ext]; %纯文件名+后缀
                otherwise
                    new_filename = [app.CurrentFolder '_' int2str(lastcount+1) ext]; %纯文件名+后缀
            end
            new_filepath = fullfile(app.RootFolder, app.CurrentFolder, new_filename); %完整路径

            %复制
            try
                copyfile(fullfile(filepath, filename), new_filepath);
                msg = '添加成功！';
            catch ME
                msg = ['添加错误： ' ME.message];
            end

            %展示结果
            m_label1 = uilabel(app.UIFigure, 'Text', msg, 'Position', [100, 100, 700, 22]);
            %3秒后消失
            t = timer;
            t.StartDelay = 3;
            t.TimerFcn = @(~,~)delete(m_label1);
            start(t);

            %刷新列表
            app.m_changeFileList();

            %改变选中文件
            app.m_Filelist.Value = new_filename;
            app.CurrentFile = app.m_Filelist.Value;
            %显示新图片
            myImage = imread(fullfile(app.RootFolder, app.CurrentFolder, app.CurrentFile));
            imshow(myImage, 'Parent', app.m_Image);
            
            %恢复delete按钮
            switch app.m_Delete.Enable
                case 'off'
                    app.m_Delete.Enable = "on";
            end
        end

        % Button pushed function: m_Delete
        function m_DeletePushed(app, event)
            %删除
            try
                delete(fullfile(app.RootFolder, app.CurrentFolder, app.CurrentFile));
                msg = '删除成功！';
            catch ME
                msg = ['删除错误： ' ME.message];
            end

            %重命名剩余文件
            %获取当前文件夹内所有图片数量（序号）
            files = dir(fullfile(app.RootFolder, app.CurrentFolder, '*.png')); %绝对路径
            %判断是否需要修改
            for i=1:length(files)
                oldname = fullfile(app.RootFolder, app.CurrentFolder, files(i).name);
                switch i
                    case {1,2,3,4,5,6,7,8,9}
                        newname = [app.RootFolder '\' app.CurrentFolder '\' app.CurrentFolder sprintf('_0%d.png', i)];
                    otherwise
                        newname = [app.RootFolder '\' app.CurrentFolder '\' app.CurrentFolder sprintf('_%d.png', i)];
                end
                if ~strcmp(oldname, newname)
                    movefile(oldname, newname)
                end
            end

            %展示结果
            m_label1 = uilabel(app.UIFigure, 'Text', msg, 'Position', [100, 100, 700, 22]);
            %3秒后消失
            t = timer;
            t.StartDelay = 3;
            t.TimerFcn = @(~,~)delete(m_label1);
            start(t);

            %刷新列表
            app.m_changeFileList();

            %取消正在选择的
            app.m_Filelist.Value = {};

            %禁用delete按钮
            switch app.m_Delete.Enable
                case 'on'
                    app.m_Delete.Enable = 'off';
            end

            %清空图片显示
            imshow([], 'Parent', app.m_Image);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: m_Folderlist
        function m_FolderlistValueChanged(app, event)
            %恢复add按钮
            switch app.m_Add.Enable
                case 'off'
                    app.m_Add.Enable = "on";
            end

            %禁用delete按钮
            switch app.m_Delete.Enable
                case 'on'
                    app.m_Delete.Enable = 'off';
            end
            
            %改变选中文件夹
            app.CurrentFolder = app.m_Folderlist.Value;

            %刷新文件列表
            app.m_changeFileList();
        end

        % Value changed function: m_Filelist
        function m_FilelistValueChanged(app, event)
            %改变选中文件
            app.CurrentFile = app.m_Filelist.Value;
            
            %恢复delete按钮
            switch app.m_Delete.Enable
                case 'off'
                    app.m_Delete.Enable = "on";
            end

            %展示选中图片
            myImage = imread(fullfile(app.RootFolder, app.CurrentFolder, app.CurrentFile));
            imshow(myImage, 'Parent', app.m_Image);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.m_Image = uiaxes(app.UIFigure);
            app.m_Add = uibutton(app.UIFigure, 'push');
            app.m_Add.ButtonPushedFcn = createCallbackFcn(app, @m_AddPushed, true);
            app.m_Add.Enable = 'off';
            app.m_Add.Text = '添加';
            app.m_Delete = uibutton(app.UIFigure, 'push');
            app.m_Delete.ButtonPushedFcn = createCallbackFcn(app, @m_DeletePushed, true);
            app.m_Delete.Enable = 'off';
            app.m_Delete.Text = '删除';
            app.m_Folderlist = uilistbox(app.UIFigure);
            app.m_Folderlist.Items = {};
            app.m_Folderlist.ValueChangedFcn = createCallbackFcn(app, @m_FolderlistValueChanged, true);
            app.m_Folderlist.Value = {};
            app.m_Filelist = uilistbox(app.UIFigure);
            app.m_Filelist.Items = {};
            app.m_Filelist.ValueChangedFcn = createCallbackFcn(app, @m_FilelistValueChanged, true);
            app.m_Filelist.Value = {};
            app.m_Anslist = uilistbox(app.UIFigure);
            app.m_Anslist.Items = {};
            app.m_Anslist.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChangeFile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end