classdef StoreFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        Learn             matlab.ui.container.Tab
        m_ChooseList_num  matlab.ui.control.ListBox
        m_SureBtn_2       matlab.ui.control.Button
        m_ChooseList      matlab.ui.control.ListBox
        m_ForgetBtn       matlab.ui.control.Button
        m_RememberBtn     matlab.ui.control.Button
        m_ReciteBtn       matlab.ui.control.UIAxes
        Store             matlab.ui.container.Tab
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label_1           matlab.ui.control.Label
        m_Folderlist      matlab.ui.control.ListBox
        m_Quslist         matlab.ui.control.ListBox
        m_Anslist         matlab.ui.control.ListBox
        m_AddFiles        matlab.ui.control.Button
        m_AddFolders      matlab.ui.control.Button
        m_Surebtn         matlab.ui.control.Button
        m_Deletebtn       matlab.ui.control.Button
        m_Image           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        RootFolder = {} %根文件夹
        CurrentFolder_names %所有子文件夹（仅名字）
        CurrentQus_names %某子文件夹内所有题目（仅名字）
        CurrentAns_names %某子文件夹内所有答案（仅名字）
        CurrentFolder %选择的文件夹（仅名字）
        CurrentQus %选择的题目（仅名字）
        CurrentAns %选择的答案（仅名字）
        Qus = '题目'
        Ans = '答案'

        ReviewNum %复习的数量
        LearningGroup %新学习的章节名
        StudyDates = zeros(100, 4) %大数组
        StudyOrder %学习顺序(数组）
        NowWhich %位置
    end

    methods (Access = private)

        function m_ChooseListFolderChange(app)
            folders = dir(app.RootFolder);
            folders(strcmp({folders(:).name}, '.') | strcmp({folders(:).name}, '..')) = [];
            app.m_ChooseList.Items = {folders.name};

            m_number = string(zeros(1, length({folders.name})));
            for i=1:length({folders.name})
                folders_1 = dir(fullfile(app.RootFolder, folders(i).name, app.Qus));
                folders_1(strcmp({folders_1(:).name}, '.') | strcmp({folders_1(:).name}, '..')) = [];
                m_number(i) = [num2str(length({folders_1.name})) '个'];
            end
            app.m_ChooseList_num.Items = m_number;
        end

        function m_RootFolder_Name(app)
            appPath = mfilename('fullpath');
            [appDir, ~, ~] = fileparts(appPath);
            app.RootFolder = fullfile(appDir, 'Fileses');
            if ~exist(app.RootFolder, 'dir')
                mkdir(app.RootFolder);
            end
        end

        function m_changeFolderList(app)%%%读取所有文件夹
            folders = dir(app.RootFolder);
            folders(strcmp({folders(:).name}, '.') | strcmp({folders(:).name}, '..')) = [];
            app.CurrentFolder_names = {folders.name};
            %显示子文件夹
            app.m_Folderlist.Items = app.CurrentFolder_names;
        end


        function m_changeQusList(app)%%%读取所有题目
            Ques = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, '*.png'));
            app.CurrentQus_names = {Ques.name};
            %显示文件
            app.m_Quslist.Items = erase(app.CurrentQus_names, ".png");
        end


        function m_changeAnsList(app)%%%读取所有答案
            Anses = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, '*.png'));
            app.CurrentAns_names = {Anses.name};
            %显示文件
            app.m_Anslist.Items = erase(app.CurrentAns_names, ".png");
        end


        function m_SureFolders(app)%%%确保"题目"和"答案"文件夹存在
            app.m_changeFolderList();
            for i=1:length(app.CurrentFolder_names)
                m_newFolders_1 = fullfile(app.RootFolder, app.CurrentFolder_names(i), app.Qus);
                m_newFolders_2 = fullfile(app.RootFolder, app.CurrentFolder_names(i), app.Ans);
                if ~exist(m_newFolders_1{1}, 'dir')
                    mkdir(m_newFolders_1{1});
                end
                if ~exist(m_newFolders_2{1}, 'dir')
                    mkdir(m_newFolders_2{1});
                end
            end
        end


        function m_newAnswer(app, picture, path)%%%新建答案图片
            % 在 UIAxes上添加一个可调整大小和位置的矩形，用于选择要裁剪的区域
            app.m_Surebtn.Visible = "on";
            rec = drawrectangle(app.m_Image);
            rec.Position = [0, 0, size(picture, 2), size(picture, 1)];%默认初始为图片大小
            % 等待用户选择区域并按下 Enter 键
            app.m_Surebtn.Enable = "on";
            wait(rec);
            % 使用 imcrop 函数将所选区域裁剪出来，并将其保存到新的文件中
            new_Ans = imcrop(picture, rec.Position);
            imwrite(new_Ans, path);
            delete(rec);
            app.m_Surebtn.Visible = "off";
            app.m_Surebtn.Enable = "off";
        end


        function m_prohibit(~, botton) %禁用按钮
            switch botton.Enable
                case 'on'
                    botton.Enable = 'off';
            end
        end


        function m_allow(~, botton) %恢复按钮
            switch botton.Enable
                case 'off'
                    botton.Enable = 'on';
            end
        end


        function m_showrst(app, msg, position)
            %展示结果
            m_label = uilabel(app.UIFigure, 'Text', msg, 'Position', position);
            m_label.FontSize = 20;
            %2秒后消失
            t = timer;
            t.StartDelay = 2;
            t.TimerFcn = @(~,~)delete(m_label);
            start(t);
        end


        function m_check(app)
            %获取当前文件夹内所有图片数量（序号）
            files_1 = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, '*.png')); %绝对路径
            files_2 = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, '*.png')); %绝对路径
            lastcount_1 = length(files_1);
            lastcount_2 = length(files_2);
            if lastcount_1 < lastcount_2
                for i=1:lastcount_2 - lastcount_1
                    if lastcount_1+i < 10
                        m_number = strcat('0', int2str(lastcount_1 + i));
                    elseif lastcount_1+i >= 10
                        m_number = int2str(lastcount_2 + i);
                    end
                    delete([app.RootFolder '\' app.CurrentFolder '\' app.Ans '\' m_number '.png']);
                end
                %刷新列表
                app.m_changeQusList();
                app.m_changeAnsList();
            elseif lastcount_2 < lastcount_1
                for i=1:lastcount_1 - lastcount_2
                    if lastcount_2+i < 10
                        m_number = strcat('0', int2str(lastcount_2 + i));
                    elseif lastcount_2+i >= 10
                        m_number = int2str(lastcount_2 + i);
                    end
                    delete([app.RootFolder '\' app.CurrentFolder '\' app.Qus '\' m_number '.png']);
                end
                %刷新列表
                app.m_changeQusList();
                app.m_changeAnsList();
            end
        end

        function m_newFolder(app)
            m_length = length(app.m_Folderlist.Items);
            app.m_Folderlist.Items(m_length+1) = cellstr('新建文件夹');
            %提示输入新名称
            prompt = {'输入新文件夹名称：'};
            dlgtitle = '命名';
            dims = [5 50];
            definput = {'新建文件夹'};
            newName = inputdlg(prompt,dlgtitle,dims,definput);
            %更新名称
            app.m_Folderlist.Items(m_length+1) = newName;
            %更新选中的目标
            app.m_Folderlist.Value = newName;
            app.m_FolderlistValueChanged(app);
            m_newFolders_1 = fullfile(app.RootFolder, newName, app.Qus);
            m_newFolders_2 = fullfile(app.RootFolder, newName, app.Ans);
            mkdir(m_newFolders_1{1});
            mkdir(m_newFolders_2{1});
        end

        function m_insert(app, i, x)
            app.StudyOrder(length(A)+1:-1:i+1) = app.StudyOrder(length(A):-1:i);
            app.StudyOrder(i) = x;
        end
        
        function m_ReadDates(app)
            fid = fopen(fullfile(app.RootFolder, 'Datas', 'RememberSituation.txt'));
            data = textscan(fid, '%s %s %s %s');
            for i = 1:size(data{1}, 1)
                app.StudyDates(i, 1) = str2num(data{1}{i});
                app.StudyDates(i, 2) = data{2}{i};
                app.StudyDates(i, 3) = data{3}{i};
                app.StudyDates(i, 4) = str2num(data{4}{i});
            end


            fclose(fid);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %获取根目录
            app.m_RootFolder_Name();
            %获取屏幕大小
            screen_size = get(groot, 'ScreenSize');
            %设置窗口位置和大小
            app.UIFigure.Position = [0, 0, screen_size(3), screen_size(4)];
            %全屏
            set(app.UIFigure, 'WindowState', 'maximized');
            %清空图片显示
            imshow([], 'Parent', app.m_Image);
            %不显示uiaxes工具栏
            app.m_Image.Toolbar.Visible = 'off';
            %确保"题目"和"答案"文件夹存在 %显示默认列表内文件
            app.m_SureFolders();
            %选择列表初始化
            app.m_ChooseListFolderChange();
        end

        % Button pushed function: m_AddFiles
        function m_AddFilesPushed(app, event)
            %获取当前文件夹内所有图片数量（序号）
            files = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, '*.png')); %绝对路径
            lastcount = length(files);

            %用户选择图片
            [filename, filepath] = uigetfile({'*.png'}, '选择要添加的图片');
            if isequal(filename, 0) || isequal(filepath, 0)
                %用户取消选择
                return;
            end

            %确定新名字+新路径
            [~, ~, ext] = fileparts(filename);
            switch lastcount+1
                case {1,2,3,4,5,6,7,8,9}
                    new_filename = ['0' int2str(lastcount+1) ext]; %纯文件名+后缀
                otherwise
                    new_filename = [int2str(lastcount+1) ext]; %纯文件名+后缀
            end
            new_filepath = fullfile(app.RootFolder, app.CurrentFolder, app.Qus, new_filename); %完整路径

            %复制
            try
                copyfile(fullfile(filepath, filename), new_filepath);
                msg = '题目添加成功！';
            catch ME
                msg = ['题目添加失败： ' ME.message];
            end

            %展示结果
            m_showrst(app, msg, [145, 190, 500, 40]);

            %刷新题目列表
            app.m_changeQusList();

            %改变选中文件
            app.m_Quslist.Value = strrep(new_filename, '.png', '');
            app.CurrentQus = [app.m_Quslist.Value '.png'];
            %显示新图片
            myImage = imread(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, app.CurrentQus));
            imshow(myImage, 'Parent', app.m_Image);

            %取消答案列表的选中
            app.CurrentAns = {};
            app.m_Anslist.Value = {};

            %禁用add按钮
            m_prohibit(app, app.m_AddFiles);

            %%%创建答案
            m_label = uilabel(app.UIFigure, 'Text', '请单击图片截取答案！', 'Position', [665, 750, 400, 100]);
            m_label.FontSize = 36;
            m_label.FontWeight = 'bold';
            new_filepath = strrep(new_filepath, app.Qus, app.Ans);
            try
                m_newAnswer(app, myImage, new_filepath);
                msg = '答案添加成功！';
            catch ME
                msg = ['答案添加失败： ' ME.message];
            end
            delete(m_label);

            %展示结果
            m_showrst(app, msg, [500, 190, 500, 40]);

            %恢复add_Files按钮
            m_allow(app, app.m_AddFiles);

            %刷新答案列表
            app.m_changeAnsList();
            %%以下是m_AnslistValueChanged(app, event)函数
            %改变选中答案
            app.m_Anslist.Value = app.m_Quslist.Value;
            app.CurrentQus = {};
            app.CurrentAns = [app.m_Anslist.Value, '.png'];
            app.m_Quslist.Value = {};
            %恢复delete按钮
            m_allow(app, app.m_Deletebtn);
            %展示选中答案
            myImage = imread(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, app.CurrentAns));
            imshow(myImage, 'Parent', app.m_Image);
            %%end

            switch app.m_Deletebtn.Visible
                case 'off'
                    app.m_Deletebtn.Visible = 'on';
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%app.UIFigure.WindowStyle = "modal"; %保证窗口最前
        end

        % Button pushed function: m_Deletebtn
        function m_DeletebtnPushed(app, event)
            if ~isempty(app.m_Quslist.Value) && isempty(app.m_Anslist.Value) %如果选中的是题目，则题目答案一起删除
                %确认是否删除
                confirmed = uiconfirm(app.UIFigure, '是否确认删除题目+答案？', '确认删除', 'Options', {'确认', '取消'}, 'DefaultOption', '取消');
                if strcmp(confirmed, '确认') %执行以下操作
                    %删除
                    try
                        delete(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, app.CurrentQus)); %删题目
                        delete(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, app.CurrentQus)); %删答案
                        msg = '删除成功！';
                    catch ME
                        msg = ['删除失败： ' ME.message];
                    end
                    m_showrst(app, msg, [860, 190, 500, 40]);

                    %重命名剩余文件
                    %获取当前文件夹内所有图片数量（序号）
                    files = dir(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, '*.png')); %绝对路径
                    %判断是否需要修改
                    for i=1:length(files)
                        oldpath = fullfile(app.RootFolder, app.CurrentFolder, app.Qus, files(i).name);
                        switch i
                            case {1,2,3,4,5,6,7,8,9}
                                newpath = [app.RootFolder '\' app.CurrentFolder '\' app.Qus '\' sprintf('0%d.png', i)];
                            otherwise
                                newpath = [app.RootFolder '\' app.CurrentFolder '\' app.Qus '\' sprintf('%d.png', i)];
                        end
                        if ~strcmp(oldpath, newpath)
                            movefile(oldpath, newpath);
                            oldpath = strrep(oldpath, app.Qus, app.Ans);
                            newpath = strrep(newpath, app.Qus, app.Ans);
                            movefile(oldpath, newpath);
                        end
                    end
                end
            elseif isempty(app.m_Quslist.Value) && ~isempty(app.m_Anslist.Value) %如果选择的是答案，则只删答案
                %确认是否删除
                confirmed = uiconfirm(app.UIFigure, '是否确认删除该答案（不会删除题目)？', '确认删除', 'Options', {'确认', '取消'}, 'DefaultOption', '取消');
                if strcmp(confirmed, '确认') %执行以下操作
                    %删除
                    new_filepath = fullfile(app.RootFolder, app.CurrentFolder, app.Ans, app.CurrentAns);
                    try
                        delete(new_filepath); %删答案
                        msg = '修改成功！';
                    catch ME
                        msg = ['修改失败： ' ME.message];
                    end
                    m_showrst(app, msg, [860, 190, 500, 40]);

                    %要求重新添加答案
                    %(请重新截取答案）
                    m_label = uilabel(app.UIFigure, 'Text', '请重新截取答案！', 'Position', [665, 700, 400, 100]);
                    m_label.FontSize = 36;
                    m_label.FontWeight = 'bold';

                    new_filepath = strrep(new_filepath, app.Qus, app.Ans);
                    %展示题目
                    app.m_Quslist.Value = app.m_Anslist.Value;
                    app.CurrentQus = app.m_Quslist.Value;
                    myImage = imread([fullfile(app.RootFolder, app.CurrentFolder, app.Qus, app.CurrentQus) '.png']);
                    imshow(myImage, 'Parent', app.m_Image);
                    try
                        m_newAnswer(app, myImage, new_filepath);
                        msg = '答案添加成功！';
                    catch ME
                        msg = ['答案添加失败： ' ME.message];
                    end
                    m_showrst(app, msg, [500, 190, 500, 40]);
                    delete(m_label);
                end
            end


            %刷新列表
            app.m_changeQusList();
            app.m_changeAnsList();

            %取消正在选择的
            app.m_Quslist.Value = {};
            app.m_Anslist.Value = {};

            %禁用delete按钮
            m_prohibit(app, app.m_Deletebtn);

            %清空图片显示
            imshow([], 'Parent', app.m_Image);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: m_Folderlist
        function m_FolderlistValueChanged(app, event)
            %恢复add_Files按钮
            m_allow(app, app.m_AddFiles);

            %禁用delete按钮
            m_prohibit(app, app.m_Deletebtn);

            %改变选中文件夹
            app.CurrentFolder = app.m_Folderlist.Value;

            %刷新文件列表
            app.m_changeQusList();
            app.m_changeAnsList();

            %检测是否题目/答案不对等
            app.m_check();

            %隐藏delete按钮
            app.m_Deletebtn.Visible = 'off';

            %显示add_Files按钮
            app.m_AddFiles.Visible = "on";
        end

        % Value changed function: m_Quslist
        function m_QuslistValueChanged(app, event)
            %改变选中题目
            app.CurrentQus = [app.m_Quslist.Value, '.png'];
            app.CurrentAns = {};
            app.m_Anslist.Value = {};

            %恢复delete按钮
            m_allow(app, app.m_Deletebtn);

            %展示选中题目
            myImage = imread(fullfile(app.RootFolder, app.CurrentFolder, app.Qus, app.CurrentQus));
            imshow(myImage, 'Parent', app.m_Image);

            %显示并修改按钮名字
            app.m_Deletebtn.Text = '删除';
            switch app.m_Deletebtn.Visible
                case 'off'
                    app.m_Deletebtn.Visible = "on";
            end
        end

        % Value changed function: m_Anslist
        function m_AnslistValueChanged(app, event)
            %改变选中答案
            app.CurrentQus = {};
            app.CurrentAns = [app.m_Anslist.Value, '.png'];
            app.m_Quslist.Value = {};

            %恢复delete按钮
            m_allow(app, app.m_Deletebtn);

            %展示选中答案
            myImage = imread(fullfile(app.RootFolder, app.CurrentFolder, app.Ans, app.CurrentAns));
            imshow(myImage, 'Parent', app.m_Image);

            %显示并修改按钮名字
            app.m_Deletebtn.Text = '修改';
            switch app.m_Deletebtn.Visible
                case 'off'
                    app.m_Deletebtn.Visible = "on";
            end
        end

        % Button pushed function: m_Surebtn
        function m_SurebtnButtonPushed(app, event)
            % 创建robot对象
            r = java.awt.Robot;
            % 模拟按下Enter键
            r.keyPress(java.awt.event.KeyEvent.VK_ENTER);
            % 模拟释放Enter键
            r.keyRelease(java.awt.event.KeyEvent.VK_ENTER);
        end

        % Button pushed function: m_AddFolders
        function m_AddFoldersButtonPushed(app, event)
            app.m_newFolder();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Learn = uitab(app.TabGroup);
            app.Learn.Title = '背诵';
            app.m_ReciteBtn = uiaxes(app.Learn);
            app.m_ReciteBtn.Visible = 'off';
            app.m_RememberBtn = uibutton(app.Learn, 'push');
            app.m_RememberBtn.Visible = 'off';
            app.m_RememberBtn.Text = '记得';
            app.m_ForgetBtn = uibutton(app.Learn, 'push');
            app.m_ForgetBtn.Visible = 'off';
            app.m_ForgetBtn.Text = '忘了';
            app.m_ChooseList = uilistbox(app.Learn);
            app.m_ChooseList.Items = {};
            app.m_ChooseList.Multiselect = 'on';
            app.m_ChooseList.Value = {};
            app.m_SureBtn_2 = uibutton(app.Learn, 'push');
            app.m_SureBtn_2.Text = '确定';
            app.m_ChooseList_num = uilistbox(app.Learn);
            app.m_ChooseList_num.Items = {};
            app.m_ChooseList_num.Value = {};
            app.Store = uitab(app.TabGroup);
            app.Store.Title = '题库';
            app.m_Image = uiaxes(app.Store);
            app.m_Deletebtn = uibutton(app.Store, 'push');
            app.m_Deletebtn.ButtonPushedFcn = createCallbackFcn(app, @m_DeletebtnPushed, true);
            app.m_Deletebtn.Enable = 'off';
            app.m_Deletebtn.Visible = 'off';
            app.m_Deletebtn.Text = '删除';
            app.m_Surebtn = uibutton(app.Store, 'push');
            app.m_Surebtn.ButtonPushedFcn = createCallbackFcn(app, @m_SurebtnButtonPushed, true);
            app.m_Surebtn.Enable = 'off';
            app.m_Surebtn.Visible = 'off';
            app.m_Surebtn.Text = '确认';
            app.m_AddFolders = uibutton(app.Store, 'push');
            app.m_AddFolders.ButtonPushedFcn = createCallbackFcn(app, @m_AddFoldersButtonPushed, true);
            app.m_AddFolders.Text = '添加文件夹';
            app.m_AddFiles = uibutton(app.Store, 'push');
            app.m_AddFiles.ButtonPushedFcn = createCallbackFcn(app, @m_AddFilesPushed, true);
            app.m_AddFiles.Enable = 'off';
            app.m_AddFiles.Visible = 'off';
            app.m_AddFiles.Text = '添加';
            app.m_Anslist = uilistbox(app.Store);
            app.m_Anslist.Items = {};
            app.m_Anslist.ValueChangedFcn = createCallbackFcn(app, @m_AnslistValueChanged, true);
            app.m_Anslist.Value = {};
            app.m_Quslist = uilistbox(app.Store);
            app.m_Quslist.Items = {};
            app.m_Quslist.ValueChangedFcn = createCallbackFcn(app, @m_QuslistValueChanged, true);
            app.m_Quslist.Value = {};
            app.m_Folderlist = uilistbox(app.Store);
            app.m_Folderlist.Items = {};
            app.m_Folderlist.ValueChangedFcn = createCallbackFcn(app, @m_FolderlistValueChanged, true);
            app.m_Folderlist.Value = {};
            app.Label_1 = uilabel(app.Store);
            app.Label_1.Text = '文件夹';
            app.Label_2 = uilabel(app.Store);
            app.Label_2.Text = '题目';
            app.Label_3 = uilabel(app.Store);
            app.Label_3.Text = '答案';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StoreFile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end